classdef FibFib < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FibFibUIFigure                  matlab.ui.Figure
        GraphOptionPanel                matlab.ui.container.Panel
        toLabel                         matlab.ui.control.Label
        fromLabel                       matlab.ui.control.Label
        YAxisAuto                       matlab.ui.control.CheckBox
        YAxisBot                        matlab.ui.control.Spinner
        YAxisTop                        matlab.ui.control.Spinner
        yaxisRangeLabel                 matlab.ui.control.Label
        TrialsPerGraph                  matlab.ui.control.Spinner
        TrialspergraphExtinctionLabel   matlab.ui.control.Label
        ArtifactRejectionPanel          matlab.ui.container.Panel
        polyfitdetrendLabel             matlab.ui.control.Label
        DetrendEnable                   matlab.ui.control.DropDown
        sLabel_5                        matlab.ui.control.Label
        ArtifactRejectionSeconds        matlab.ui.control.Spinner
        IgnoreinitialLabel_3            matlab.ui.control.Label
        RunPanel                        matlab.ui.container.Panel
        PrintAUCButton                  matlab.ui.control.Button
        DrawallgraphButton              matlab.ui.control.Button
        DrawgraphsButton                matlab.ui.control.Button
        FilterPanel_2                   matlab.ui.container.Panel
        FilterStrength                  matlab.ui.control.Spinner
        smovingaveragefilterLabel       matlab.ui.control.Label
        FilterEnable                    matlab.ui.control.DropDown
        TimewindowPanel                 matlab.ui.container.Panel
        DuringconditiongUSispresentedLabel  matlab.ui.control.Label
        USOffset                        matlab.ui.control.NumericEditField
        sbeforeCSendsLabel              matlab.ui.control.Label
        TimeWindow2                     matlab.ui.control.NumericEditField
        saftertheCSLabel                matlab.ui.control.Label
        sbeforeandLabel                 matlab.ui.control.Label
        TimeWindow1                     matlab.ui.control.NumericEditField
        UsedataduringLabel              matlab.ui.control.Label
        BaselineCorrectionPanel         matlab.ui.container.Panel
        DropdownCorrectStd              matlab.ui.control.DropDown
        baselineusingstdactivityLabel   matlab.ui.control.Label
        DropdownStdMethod               matlab.ui.control.DropDown
        IgnoreinitialLabel_2            matlab.ui.control.Label
        BaselineStdIgnore               matlab.ui.control.Spinner
        sanduseLabel_2                  matlab.ui.control.Label
        BaselineStdUse                  matlab.ui.control.Spinner
        sdataLabel_2                    matlab.ui.control.Label
        sdataLabel                      matlab.ui.control.Label
        BaselineMeanUse                 matlab.ui.control.Spinner
        sanduseLabel                    matlab.ui.control.Label
        BaselineMeanIgnore              matlab.ui.control.Spinner
        IgnoreinitialLabel              matlab.ui.control.Label
        DropdownCorrectMean             matlab.ui.control.DropDown
        baselineusingmeanactivityLabel  matlab.ui.control.Label
        DropdownMeanMethod              matlab.ui.control.DropDown
        TanksPanel                      matlab.ui.container.Panel
        AddTanksButton                  matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        AddTankButton                   matlab.ui.control.Button
        TankList                        matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        BasePath                        matlab.ui.control.EditField
        BasePathEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        fs = 1.017252624511719e+03;
    end
    
    methods (Access = private)
        
        function options = readProcessOptions(app)
            options.verbose = false;
            options.timewindow = [-app.TimeWindow1.Value, app.TimeWindow2.Value];
            options.us_offset = app.USOffset.Value;
            if strcmp(app.DropdownCorrectMean.Value, "Correct")
                if strcmp(app.DropdownCorrectStd.Value, "Correct")
                    options.baseline_correction = "z";
                else
                    options.baseline_correction = "zero";
                end
            else
                options.baseline_correction = "none";
            end
            if strcmp(app.DropdownMeanMethod.Value, "from every Timewindow.")
                if strcmp(app.DropdownStdMethod.Value, "from every Timewindow.")
                    options.baseline_mode = "trial";
                else
                    options.baseline_mode = "mix";
                end
            else
                options.baseline_mode = "whole";
            end
            options.baseline_trial_duration = app.BaselineMeanUse.Value;
            options.baseline_trial_ignore_duration = app.BaselineMeanIgnore.Value;
            options.baseline_whole_duration = app.BaselineMeanUse.Value;
            options.baseline_whole_ignore_duration = app.BaselineMeanIgnore.Value;
            options.baseline_mix_duration = [app.BaselineMeanUse.Value, app.BaselineStdUse.Value];
            options.baseline_mix_ignore_duration = [app.BaselineMeanIgnore.Value, app.BaselineStdIgnore.Value];
            options.filter = app.FilterStrength.Value;
            options.initial_artifact_remove_time = app.ArtifactRejectionSeconds.Value;
            if strcmp(app.DetrendEnable.Value, "Do not use")
                options.disable_detrending = false;
            else
                options.disable_detrending = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropdownCorrectMean
        function DropdownCorrectMeanValueChanged(app, event)
            value = app.DropdownCorrectMean.Value;
            if strcmp(value, "Correct")
                app.DropdownCorrectStd.Enable = "on";
            else
                app.DropdownCorrectStd.Enable = "off";
                app.DropdownCorrectStd.Value = "Do not correct";
            end
        end

        % Value changed function: DropdownMeanMethod
        function DropdownMeanMethodValueChanged(app, event)
            if strcmp(app.DropdownMeanMethod.Value, "from every Timewindow.")
                app.BaselineMeanIgnore.Value = 0;
                app.BaselineMeanUse.Value = 1;
                % Setup Std conditions
                app.DropdownStdMethod.Enable = "on";
                app.BaselineStdIgnore.Enable = "on";
                app.BaselineStdUse.Enable = "on";
            else
                app.BaselineMeanIgnore.Value = 60;
                app.BaselineMeanUse.Value = 60;
                % force std method to "whole"
                app.DropdownStdMethod.Enable = "off";
                app.DropdownStdMethod.Value = "at the beginning of the experiment.";
                app.BaselineStdIgnore.Value = app.BaselineMeanIgnore.Value;
                app.BaselineStdUse.Value = app.BaselineMeanUse.Value;
                app.BaselineStdIgnore.Enable = "off";
                app.BaselineStdUse.Enable = "off";
            end               
        end

        % Value changed function: BaselineMeanIgnore, BaselineMeanUse
        function BaselineMeanIgnoreValueChanged(app, event)
            if strcmp(app.DropdownMeanMethod.Value, app.DropdownStdMethod.Value)
                app.BaselineStdIgnore.Value = app.BaselineMeanIgnore.Value;
                app.BaselineStdUse.Value = app.BaselineMeanUse.Value;
            end
        end

        % Value changed function: DropdownStdMethod
        function DropdownStdMethodValueChanged(app, event)
            if strcmp(app.DropdownStdMethod.Value, "from every Timewindow.")
                app.BaselineStdIgnore.Value = app.BaselineMeanIgnore.Value;
                app.BaselineStdUse.Value = app.BaselineMeanUse.Value;
                app.BaselineStdIgnore.Enable = "off";
                app.BaselineStdUse.Enable = "off";
            else
                app.BaselineStdIgnore.Value = 60;
                app.BaselineStdUse.Value = 60;
                app.BaselineStdIgnore.Enable = "on";
                app.BaselineStdUse.Enable = "on";
            end            
        end

        % Value changed function: FilterEnable
        function FilterEnableValueChanged(app, event)
            if strcmp(app.FilterEnable.Value, "Use")
                app.FilterStrength.Enable = "on";
                app.FilterStrength.Value = 0.5;
            else
                app.FilterStrength.Enable = "off";
                app.FilterStrength.Value = 0;
            end
        end

        % Value changed function: FilterStrength
        function FilterStrengthValueChanged(app, event)
            if app.FilterStrength.Value == 0
                app.FilterEnable.Value = "Do not use";
                app.FilterStrength.Enable = "off";
            end
            
        end

        % Button pushed function: AddTankButton
        function AddTankButtonPushed(app, event)
            selpath = uigetdir(app.BasePath.Value, 'Select Tank');
            if selpath ~= 0 
                app.TankList.Items = [app.TankList.Items, selpath];
            end
        end

        % Button pushed function: AddTanksButton
        function AddTanksButtonPushed(app, event)
            selpath = uigetdir(app.BasePath.Value, 'Select Tank Parent Folder');
            if selpath ~= 0 
                list = dir(selpath);
                Tanks = {list([list.isdir]).name};
                Tanks = Tanks(3:end);
                for idx = 1 : numel(Tanks)
                    app.TankList.Items = [app.TankList.Items, ...
                        fullfile(selpath, Tanks(idx))];
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~isempty(app.TankList.Value)
                app.TankList.Items(cell2mat(cellfun(@(e) strcmp(e, app.TankList.Value), app.TankList.Items, 'UniformOutput', false))) = [];
                app.TankList.Value = {};
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.TankList.Items = {};
            app.TankList.Value = {};
        end

        % Button pushed function: DrawgraphsButton
        function DrawgraphsButtonPushed(app, event)
            for items = app.TankList.Items
                options = readProcessOptions(app);
                options = [namedargs2cell(options), 'draw_ribbon_result', false, 'extinction_trials_per_graph', app.TrialsPerGraph.Value];
                drawFibFigure(cell2mat(items), options{:});
                if app.YAxisAuto.Value == false
                    fig = gcf;
                    for ax = fig.Children
                        ylim(ax, [app.YAxisBot.Value, app.YAxisTop.Value]);
                    end
                end
            end
        end

        % Button pushed function: DrawallgraphButton
        function DrawallgraphButtonPushed(app, event)
            
        end

        % Value changed function: YAxisAuto
        function YAxisAutoValueChanged(app, event)
            if app.YAxisAuto.Value
                app.YAxisBot.Enable = "off";
                app.YAxisTop.Enable = "off";
            else
                app.YAxisBot.Enable = "on";
                app.YAxisTop.Enable = "on";
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FibFibUIFigure = uifigure('Visible', 'off');
            app.FibFibUIFigure.Name = 'FibFib';
            app.TanksPanel = uipanel(app.FibFibUIFigure);
            app.TanksPanel.Title = 'Tanks';
            app.BasePathEditFieldLabel = uilabel(app.TanksPanel);
            app.BasePathEditFieldLabel.Text = 'Base Path';
            app.BasePath = uieditfield(app.TanksPanel, 'text');
            app.BasePath.Value = 'C:\';
            app.ListBoxLabel = uilabel(app.TanksPanel);
            app.ListBoxLabel.Text = 'List Box';
            app.TankList = uilistbox(app.TanksPanel);
            app.TankList.Items = {};
            app.TankList.Value = {};
            app.AddTankButton = uibutton(app.TanksPanel, 'push');
            app.AddTankButton.ButtonPushedFcn = createCallbackFcn(app, @AddTankButtonPushed, true);
            app.AddTankButton.Text = 'Add Tank';
            app.ClearButton = uibutton(app.TanksPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ClearAllButton = uibutton(app.TanksPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.AddTanksButton = uibutton(app.TanksPanel, 'push');
            app.AddTanksButton.ButtonPushedFcn = createCallbackFcn(app, @AddTanksButtonPushed, true);
            app.AddTanksButton.Text = 'Add Tanks';
            app.BaselineCorrectionPanel = uipanel(app.FibFibUIFigure);
            app.BaselineCorrectionPanel.Title = 'Baseline Correction';
            app.DropdownMeanMethod = uidropdown(app.BaselineCorrectionPanel);
            app.DropdownMeanMethod.Items = {'from every Timewindow.', 'at the beginning of the experiment.'};
            app.DropdownMeanMethod.ValueChangedFcn = createCallbackFcn(app, @DropdownMeanMethodValueChanged, true);
            app.DropdownMeanMethod.Value = 'from every Timewindow.';
            app.baselineusingmeanactivityLabel = uilabel(app.BaselineCorrectionPanel);
            app.baselineusingmeanactivityLabel.Text = 'baseline using mean activity';
            app.DropdownCorrectMean = uidropdown(app.BaselineCorrectionPanel);
            app.DropdownCorrectMean.Items = {'Correct', 'Do not correct'};
            app.DropdownCorrectMean.ValueChangedFcn = createCallbackFcn(app, @DropdownCorrectMeanValueChanged, true);
            app.DropdownCorrectMean.Value = 'Correct';
            app.IgnoreinitialLabel = uilabel(app.BaselineCorrectionPanel);
            app.IgnoreinitialLabel.Text = 'Ignore initial';
            app.BaselineMeanIgnore = uispinner(app.BaselineCorrectionPanel);
            app.BaselineMeanIgnore.Limits = [0 Inf];
            app.BaselineMeanIgnore.ValueChangedFcn = createCallbackFcn(app, @BaselineMeanIgnoreValueChanged, true);
            app.sanduseLabel = uilabel(app.BaselineCorrectionPanel);
            app.sanduseLabel.Text = 's and use';
            app.BaselineMeanUse = uispinner(app.BaselineCorrectionPanel);
            app.BaselineMeanUse.Limits = [0 Inf];
            app.BaselineMeanUse.ValueChangedFcn = createCallbackFcn(app, @BaselineMeanIgnoreValueChanged, true);
            app.BaselineMeanUse.Value = 1;
            app.sdataLabel = uilabel(app.BaselineCorrectionPanel);
            app.sdataLabel.Text = 's data.';
            app.sdataLabel_2 = uilabel(app.BaselineCorrectionPanel);
            app.sdataLabel_2.Text = 's data.';
            app.BaselineStdUse = uispinner(app.BaselineCorrectionPanel);
            app.BaselineStdUse.Limits = [0 Inf];
            app.BaselineStdUse.Value = 60;
            app.sanduseLabel_2 = uilabel(app.BaselineCorrectionPanel);
            app.sanduseLabel_2.Text = 's and use';
            app.BaselineStdIgnore = uispinner(app.BaselineCorrectionPanel);
            app.BaselineStdIgnore.Limits = [0 Inf];
            app.BaselineStdIgnore.Value = 60;
            app.IgnoreinitialLabel_2 = uilabel(app.BaselineCorrectionPanel);
            app.IgnoreinitialLabel_2.Text = 'Ignore initial';
            app.DropdownStdMethod = uidropdown(app.BaselineCorrectionPanel);
            app.DropdownStdMethod.Items = {'from every Timewindow.', 'at the beginning of the experiment.'};
            app.DropdownStdMethod.ValueChangedFcn = createCallbackFcn(app, @DropdownStdMethodValueChanged, true);
            app.DropdownStdMethod.Value = 'at the beginning of the experiment.';
            app.baselineusingstdactivityLabel = uilabel(app.BaselineCorrectionPanel);
            app.baselineusingstdactivityLabel.Text = 'baseline using std activity';
            app.DropdownCorrectStd = uidropdown(app.BaselineCorrectionPanel);
            app.DropdownCorrectStd.Items = {'Correct', 'Do not correct'};
            app.DropdownCorrectStd.Value = 'Correct';
            app.TimewindowPanel = uipanel(app.FibFibUIFigure);
            app.TimewindowPanel.Title = 'Timewindow';
            app.UsedataduringLabel = uilabel(app.TimewindowPanel);
            app.UsedataduringLabel.Text = 'Use data during';
            app.TimeWindow1 = uieditfield(app.TimewindowPanel, 'numeric');
            app.TimeWindow1.Limits = [0 Inf];
            app.TimeWindow1.ValueDisplayFormat = '%.1f';
            app.TimeWindow1.Value = 5;
            app.sbeforeandLabel = uilabel(app.TimewindowPanel);
            app.sbeforeandLabel.Text = 's before, and';
            app.saftertheCSLabel = uilabel(app.TimewindowPanel);
            app.saftertheCSLabel.Text = 's after the CS.';
            app.TimeWindow2 = uieditfield(app.TimewindowPanel, 'numeric');
            app.TimeWindow2.Limits = [0 Inf];
            app.TimeWindow2.ValueDisplayFormat = '%.1f';
            app.TimeWindow2.Value = 20;
            app.sbeforeCSendsLabel = uilabel(app.TimewindowPanel);
            app.sbeforeCSendsLabel.Text = 's before CS ends.';
            app.USOffset = uieditfield(app.TimewindowPanel, 'numeric');
            app.USOffset.Limits = [0 Inf];
            app.USOffset.ValueDisplayFormat = '%.1f';
            app.USOffset.Value = 2.5;
            app.DuringconditiongUSispresentedLabel = uilabel(app.TimewindowPanel);
            app.DuringconditiongUSispresentedLabel.Text = 'During conditiong, US is presented ';
            app.FilterPanel_2 = uipanel(app.FibFibUIFigure);
            app.FilterPanel_2.Title = 'Filter';
            app.FilterEnable = uidropdown(app.FilterPanel_2);
            app.FilterEnable.Items = {'Use', 'Do not use'};
            app.FilterEnable.ValueChangedFcn = createCallbackFcn(app, @FilterEnableValueChanged, true);
            app.FilterEnable.Value = 'Do not use';
            app.smovingaveragefilterLabel = uilabel(app.FilterPanel_2);
            app.smovingaveragefilterLabel.Text = 's moving average filter.';
            app.FilterStrength = uispinner(app.FilterPanel_2);
            app.FilterStrength.Limits = [0 Inf];
            app.FilterStrength.ValueChangedFcn = createCallbackFcn(app, @FilterStrengthValueChanged, true);
            app.FilterStrength.Enable = 'off';
            app.RunPanel = uipanel(app.FibFibUIFigure);
            app.RunPanel.Title = 'Run';
            app.DrawgraphsButton = uibutton(app.RunPanel, 'push');
            app.DrawgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @DrawgraphsButtonPushed, true);
            app.DrawgraphsButton.Text = 'Draw graphs';
            app.DrawallgraphButton = uibutton(app.RunPanel, 'push');
            app.DrawallgraphButton.ButtonPushedFcn = createCallbackFcn(app, @DrawallgraphButtonPushed, true);
            app.DrawallgraphButton.Text = 'Draw all graph';
            app.PrintAUCButton = uibutton(app.RunPanel, 'push');
            app.PrintAUCButton.Text = 'Print AUC';
            app.ArtifactRejectionPanel = uipanel(app.FibFibUIFigure);
            app.ArtifactRejectionPanel.Title = 'Artifact Rejection';
            app.IgnoreinitialLabel_3 = uilabel(app.ArtifactRejectionPanel);
            app.IgnoreinitialLabel_3.Text = 'Ignore initial';
            app.ArtifactRejectionSeconds = uispinner(app.ArtifactRejectionPanel);
            app.ArtifactRejectionSeconds.Limits = [0 Inf];
            app.ArtifactRejectionSeconds.Value = 30;
            app.sLabel_5 = uilabel(app.ArtifactRejectionPanel);
            app.sLabel_5.Text = 's';
            app.DetrendEnable = uidropdown(app.ArtifactRejectionPanel);
            app.DetrendEnable.Items = {'Use', 'Do not use'};
            app.DetrendEnable.Value = 'Use';
            app.polyfitdetrendLabel = uilabel(app.ArtifactRejectionPanel);
            app.polyfitdetrendLabel.Text = 'polyfit detrend.';
            app.GraphOptionPanel = uipanel(app.FibFibUIFigure);
            app.GraphOptionPanel.Title = 'Graph Option';
            app.TrialspergraphExtinctionLabel = uilabel(app.GraphOptionPanel);
            app.TrialspergraphExtinctionLabel.Text = 'Trials per graph (Extinction)';
            app.TrialsPerGraph = uispinner(app.GraphOptionPanel);
            app.TrialsPerGraph.Limits = [0 Inf];
            app.TrialsPerGraph.Value = 6;
            app.yaxisRangeLabel = uilabel(app.GraphOptionPanel);
            app.yaxisRangeLabel.Text = 'yaxis Range';
            app.YAxisTop = uispinner(app.GraphOptionPanel);
            app.YAxisTop.Enable = 'off';
            app.YAxisTop.Value = 6;
            app.YAxisBot = uispinner(app.GraphOptionPanel);
            app.YAxisBot.Enable = 'off';
            app.YAxisBot.Value = -3;
            app.YAxisAuto = uicheckbox(app.GraphOptionPanel);
            app.YAxisAuto.ValueChangedFcn = createCallbackFcn(app, @YAxisAutoValueChanged, true);
            app.YAxisAuto.Text = 'Auto';
            app.YAxisAuto.Value = true;
            app.fromLabel = uilabel(app.GraphOptionPanel);
            app.fromLabel.Text = 'from';
            app.toLabel = uilabel(app.GraphOptionPanel);
            app.toLabel.Text = 'to';
            % Show the figure after all components are created
            app.FibFibUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FibFib

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FibFibUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.FibFibUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FibFibUIFigure)
        end
    end
end